classdef DatasetSelectionScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ConfirmButton              matlab.ui.control.Button
        DatasetSplitsButtonGroup   matlab.ui.container.ButtonGroup
        CrossvalidationButton      matlab.ui.control.RadioButton
        CrossValidationPanel       matlab.ui.container.Panel
        CrossValidationParameterEditField  matlab.ui.control.NumericEditField
        CrossValidationsDropDown   matlab.ui.control.DropDown
        MakeTrainTestValidationsplitsPanel  matlab.ui.container.Panel
        TestField                  matlab.ui.control.NumericEditField
        TestSplitSlider            matlab.ui.control.Slider
        TestsplitLabel             matlab.ui.control.Label
        TrainingField              matlab.ui.control.NumericEditField
        TrainingSplitSlider        matlab.ui.control.Slider
        TrainingsplitSliderLabel   matlab.ui.control.Label
        MakeTrainTestsplitsButton  matlab.ui.control.RadioButton
        DatasetLoadingStatus       matlab.ui.control.Label
        LoadDatasetsFolderButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
        responseToMainApp = struct('testingMethod', 'KFoldCrossValidation',...
                                   'crossValidationParameter', 5,...
                                   'crossValidationTestSplit', 10,...
                                   'trainSplit',70,...
                                   'testSplit', 30);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainScreen)
            % Store main app object
            app.MainApp = MainScreen;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % enable the orignal button
            app.MainApp.LoadDatasetButton.Enable = 'on';
            delete(app);
        end

        % Button pushed function: LoadDatasetsFolderButton
        function LoadDatasetsFolderButtonPushed(app, event)
            if strcmp(app.LoadDatasetsFolderButton.Text, 'Load datasets Folder')
                datasetsFolder = uigetdir("datasets\");
                
                % For focus.
                drawnow; 
                figure(app.UIFigure);
                
                if datasetsFolder == 0
                    app.DatasetLoadingStatus.FontColor = [1,0,0];
                    app.DatasetLoadingStatus.Text = 'User hit Cancel';
                else
                    app.LoadDatasetsFolderButton.Text = 'Cancel';
                    folderName = split(datasetsFolder, '\');
                    app.DatasetLoadingStatus.Text = ['Folder Selected: ' char(folderName(end))];
                    app.DatasetLoadingStatus.FontColor = [0.39,0.83,0.07];
                    app.responseToMainApp.datasetsFolder = strcat(datasetsFolder, '\');
                    
                    % TODO: add options to change below.
                    app.responseToMainApp.datasetExtension = '*.txt';
                    
                    app.DatasetSplitsButtonGroup.Enable = 'on';
                    app.DatasetSplitsButtonGroup.Enable = 'on';
                end
            elseif strcmp(app.LoadDatasetsFolderButton.Text, 'Cancel')
                app.LoadDatasetsFolderButton.Text = 'Load datasets Folder';
                app.DatasetLoadingStatus.FontColor = [1,0,0];
                app.DatasetLoadingStatus.Text = 'No datasets folder loaded';
                app.responseToMainApp.datasets = '';
                app.DatasetSplitsButtonGroup.Enable = 'off';
            end
        end

        % Selection changed function: DatasetSplitsButtonGroup
        function DatasetSplitsButtonGroupSelectionChanged(app, event)
            selectedButton = app.DatasetSplitsButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Make Train-Test splits')
                app.responseToMainApp.testingMethod = 'TrainTestValidation';


                app.MakeTrainTestValidationsplitsPanel.Enable = 'on';
                app.CrossValidationPanel.Enable = 'off';
            elseif strcmp(selectedButton.Text, 'Crossvalidation')
                % Default values
                app.CrossValidationsDropDown.Value = 'K-Fold';
                app.CrossValidationParameterEditField.Value = 5;
                
                % Default values.
                app.responseToMainApp.testingMethod = 'KFoldCrossValidation';
                app.responseToMainApp.crossValidationParameter = app.CrossValidationParameterEditField.Value;
                
                app.CrossValidationPanel.Enable = 'on';
                app.MakeTrainTestValidationsplitsPanel.Enable = 'off';
            end
        end

        % Value changed function: CrossValidationsDropDown
        function CrossValidationsDropDownValueChanged(app, event)
            value = app.CrossValidationsDropDown.Value;
            if strcmp(value, 'K-Fold')
                app.responseToMainApp.testingMethod = 'KFoldCrossValidation';
                app.CrossValidationParameterEditField.Limits = [1, Inf];
                app.CrossValidationParameterEditField.Value = 5;
                app.responseToMainApp.crossValidationParameter = app.CrossValidationParameterEditField.Value;
                
            elseif strcmp(value, 'Hold-Out')
                app.responseToMainApp.testingMethod = 'HoldOutCrossValidation';
                app.CrossValidationParameterEditField.Limits = [0, 1];
                app.CrossValidationParameterEditField.Value = 0.1;
                app.responseToMainApp.crossValidationParameter = app.CrossValidationParameterEditField.Value;
                
            elseif strcmp(value, 'Leave M Out')
                app.CrossValidationParameterEditField.Limits = [1, Inf];
                app.responseToMainApp.testingMethod = 'LeaveMOutCrossValidation';
                app.CrossValidationParameterEditField.Limits = [1, Inf];
                app.CrossValidationParameterEditField.Value = 5;
                app.responseToMainApp.crossValidationParameter = app.CrossValidationParameterEditField.Value;
                
            end
            
        end

        % Value changed function: TrainingSplitSlider
        function TrainingSplitSliderValueChanged(app, event)
            value = app.TrainingSplitSlider.Value;
            app.TrainingField.Value = value;
            app.TestSplitSlider.Limits = [0, 100-value];
            app.TestField.Value = 100-value;
            app.responseToMainApp.trainSplit = value;
            app.responseToMainApp.testSplit = 100 - value;
            if 100-value < app.TestField.Value
                app.TestSplitSlider.Value = 100-value;
                app.TestField.Value = 100-value;
            end
        end

        % Value changed function: TrainingField
        function TrainingFieldValueChanged(app, event)
            value = app.TrainingField.Value;
%             if value > app.TrainingSplitSlider.Limits(2)
%                 app.TestField.Value = app.TrainingSplitSlider.Limits(2)-0.001;
%             elseif value < app.TrainingSplitSlider.Limits(1)
%                 app.TestField.Value = app.TrainingSplitSlider.Limits(1)+1;
%             end
%             value = app.TrainingField.Value;
            
            app.TrainingSplitSlider.Value = value;
            app.TestSplitSlider.Limits = [0, 100-value];
            app.TestField.Limits = [0, 100-value];
            app.TestField.Value = 100-value;
            app.responseToMainApp.trainSplit = value;
            app.responseToMainApp.testSplit = 100 - value;
            if 100-value < app.TestField.Value
                app.TestSplitSlider.Value = 100-value;
                app.TestField.Value = 100-value;
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % TODO: Check if below is nessary
            app.responseToMainApp.success = true;
            
            sendDataToMainApp(app.MainApp, app.responseToMainApp)
            app.MainApp.LoadDatasetButton.Enable = 'on';
            app.MainApp.ChooseClassifierPanel.Enable = 'on';
            
            delete(app);
        end

        % Value changed function: CrossValidationParameterEditField
        function CrossValidationParameterEditFieldValueChanged(app, event)
            value = app.CrossValidationParameterEditField.Value;
            app.responseToMainApp.crossValidationParameter = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LoadDatasetsFolderButton = uibutton(app.UIFigure, 'push');
            app.LoadDatasetsFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetsFolderButtonPushed, true);
            app.LoadDatasetsFolderButton.Text = 'Load datasets Folder';
            app.DatasetLoadingStatus = uilabel(app.UIFigure);
            app.DatasetLoadingStatus.Text = 'No datasets folder loaded';
            app.DatasetSplitsButtonGroup = uibuttongroup(app.UIFigure);
            app.DatasetSplitsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatasetSplitsButtonGroupSelectionChanged, true);
            app.DatasetSplitsButtonGroup.Enable = 'off';
            app.DatasetSplitsButtonGroup.Title = 'Dataset Splits';
            app.MakeTrainTestsplitsButton = uiradiobutton(app.DatasetSplitsButtonGroup);
            app.MakeTrainTestsplitsButton.Text = 'Make Train-Test splits';
            app.MakeTrainTestValidationsplitsPanel = uipanel(app.DatasetSplitsButtonGroup);
            app.MakeTrainTestValidationsplitsPanel.Enable = 'off';
            app.TrainingsplitSliderLabel = uilabel(app.MakeTrainTestValidationsplitsPanel);
            app.TrainingsplitSliderLabel.Text = 'Training split (%)';
            app.TrainingSplitSlider = uislider(app.MakeTrainTestValidationsplitsPanel);
            app.TrainingSplitSlider.Limits = [1 99];
            app.TrainingSplitSlider.ValueChangedFcn = createCallbackFcn(app, @TrainingSplitSliderValueChanged, true);
            app.TrainingSplitSlider.Value = 70;
            app.TrainingField = uieditfield(app.MakeTrainTestValidationsplitsPanel, 'numeric');
            app.TrainingField.LowerLimitInclusive = 'off';
            app.TrainingField.UpperLimitInclusive = 'off';
            app.TrainingField.Limits = [0 100];
            app.TrainingField.ValueChangedFcn = createCallbackFcn(app, @TrainingFieldValueChanged, true);
            app.TrainingField.Value = 70;
            app.TestsplitLabel = uilabel(app.MakeTrainTestValidationsplitsPanel);
            app.TestsplitLabel.Enable = 'off';
            app.TestsplitLabel.Text = 'Test split (%)';
            app.TestSplitSlider = uislider(app.MakeTrainTestValidationsplitsPanel);
            app.TestSplitSlider.Limits = [0 30];
            app.TestSplitSlider.Enable = 'off';
            app.TestSplitSlider.Value = 30;
            app.TestField = uieditfield(app.MakeTrainTestValidationsplitsPanel, 'numeric');
            app.TestField.LowerLimitInclusive = 'off';
            app.TestField.UpperLimitInclusive = 'off';
            app.TestField.Enable = 'off';
            app.TestField.Value = 30;
            app.CrossValidationPanel = uipanel(app.DatasetSplitsButtonGroup);
            app.CrossValidationsDropDown = uidropdown(app.CrossValidationPanel);
            app.CrossValidationsDropDown.Items = {'K-Fold', 'Hold-Out', 'Leave M Out'};
            app.CrossValidationsDropDown.ValueChangedFcn = createCallbackFcn(app, @CrossValidationsDropDownValueChanged, true);
            app.CrossValidationsDropDown.Value = 'K-Fold';
            app.CrossValidationParameterEditField = uieditfield(app.CrossValidationPanel, 'numeric');
            app.CrossValidationParameterEditField.Limits = [1 Inf];
            app.CrossValidationParameterEditField.ValueChangedFcn = createCallbackFcn(app, @CrossValidationParameterEditFieldValueChanged, true);
            app.CrossValidationParameterEditField.Tooltip = {'CrossValidation Parameter'};
            app.CrossValidationParameterEditField.Value = 5;
            app.CrossvalidationButton = uiradiobutton(app.DatasetSplitsButtonGroup);
            app.CrossvalidationButton.Text = 'Crossvalidation';
            app.CrossvalidationButton.Value = true;
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatasetSelectionScreen(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end