classdef MainScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveCurrentConfigurationMenu    matlab.ui.container.Menu
        LoadConfigurationMenu           matlab.ui.container.Menu
        ChangeClassifiersDirectoryMenu  matlab.ui.container.Menu
        ChangeSaveLocationMenu          matlab.ui.container.Menu
        OptionsMenu                     matlab.ui.container.Menu
        RefreshClassifiersMenu          matlab.ui.container.Menu
        StatusReporter                  matlab.ui.control.Label
        DatasetLoadingStatus            matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        HyperparameterTuningPanel       matlab.ui.container.Panel
        SaveResultsforallHyperparametersCheckBox  matlab.ui.control.CheckBox
        ConfirmHyperparameterSettingsButton  matlab.ui.control.Button
        HyperparameterSettingPanel      matlab.ui.container.Panel
        RandomSizeDropDownEditField     matlab.ui.control.NumericEditField
        RandomSizeTypeDropDown          matlab.ui.control.DropDown
        TuningmethodDropDown            matlab.ui.control.DropDown
        TuningmethodDropDownLabel       matlab.ui.control.Label
        ChooseClassifierPanel           matlab.ui.container.Panel
        ConfigureClassifierButton       matlab.ui.control.Button
        CustomClassifierLoadingStatus   matlab.ui.control.Label
        Hyperlink                       matlab.ui.control.Hyperlink
        ClassifierSelectionTree         matlab.ui.container.Tree
        LoadDatasetButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        DatasetSelectionApp % The variable to store the Dataset Selection App
        
        % To store the response of the Dataset Selection App
        DatasetSelectionAppResponse = struct(   'success', false, ...
                                                'modes', {}, ...
                                                'datasets', {}, ...
                                                'testingMethod', '', ...
                                                'crossvalidation_parameter', 0)
        CustomClassifierFilePath % Description
        absPath = fileparts(mfilename('fullpath')) % Description
        appSettings % Description
        currentConfiguration % Description
        selectedClassifier % Description
        selectedHyperparameterSettings % Description
        stopTraining = false % Description
        resultsFile % Description
        parametersFile % Description
        saveLocation % Description
    end
    
    properties (Constant)

    end
    
    methods (Access = public)
        
        function sendDataToMainApp(app, response)
            app.DatasetSelectionAppResponse = response;
            updateDatasetLoadingStatus(app);
            
            % For focus
            drawnow; 
            figure(app.UIFigure);
        end
    end
    
    methods (Access = private)
        
        function updateDatasetLoadingStatus(app)
            % Update the Dataset loaded textbox if the response was a success.
            if app.DatasetSelectionAppResponse.success
                app.DatasetLoadingStatus.FontColor = [0.39,0.83,0.07];
                app.DatasetLoadingStatus.Text = 'Dataset(s) Loaded!';
            else
                app.DatasetLoadingStatus.FontColor = [1,0,0];
                app.DatasetLoadingStatus.Text = 'Dataset Loading Failed!';
            end
        end
        
        function loadClassifiersToTree(app)
            % Display "Loading"
            updateStatusReporter(app, 'Loading Classifiers', 'black');
            try
                % Remove all pre-existing nodes in the tree.
                app.ClassifierSelectionTree.Children.delete;
                
                baseClassifierTypes = dir(app.appSettings.classifiersPath);
                
                % Ensuring only folders get loaded and no files.
                dirFlags = [baseClassifierTypes.isdir];
                baseClassifierTypes = baseClassifierTypes(dirFlags);
                
                % removing . and ..
                baseClassifierTypes = {baseClassifierTypes(3:end).name};
                
                % Building the tree
                for i=1:length(baseClassifierTypes)
                    % Building the base
                    classifierTypeNodeData = struct('nodeType','classifierType');
                    baseClassifierType(i)=uitreenode(app.ClassifierSelectionTree,...
                        'Text',string(baseClassifierTypes(i)),...
                        'NodeData', classifierTypeNodeData); %#ok<AGROW> 
                    classifiers = dir(app.appSettings.classifiersPath + string(baseClassifierTypes(i)));
                    
                    % Ensuring only folders get loaded and no files.
                    dirFlags = [classifiers.isdir];
                    classifiers = classifiers(dirFlags);
                    
                    % removing . and ..
                    classifiers = {classifiers(3:end).name};
                    
                    for j=1:length(classifiers)
                        try
                            settingsPath = app.appSettings.classifiersPath + string(baseClassifierTypes(i)) + '/'+ string(classifiers(j)) + '/settings.xml';
                            settingsMAT = readstruct(settingsPath);
                            uitreenode(baseClassifierType(i),'Text',string(classifiers(j)),'NodeData',settingsMAT);
                        catch
                            % TODO: make more contengencies, refine
                            % defaultclassifierNodeData for better
                            % everthing.
                            defaultclassifierNodeData = struct('nodeType','noSettingsClassifier');
                            uitreenode(baseClassifierType(i),'Text',string(classifiers(j)),'NodeData',defaultclassifierNodeData);
                        end
                    end
                end
                updateStatusReporter(app, 'Classifiers Loaded Successfully!', 'green');
            catch ME
                updateStatusReporter(app, ['Loading Failed | Error: ', ME.identifier], 'red');
            end
            
        end
        
        function updateStatusReporter(app, msg, color)
            if strcmp(color, 'black')
                color = [0,0,0];
            elseif strcmp(color, 'green')
                color = [0.39,0.83,0.07];
            elseif strcmp(color, 'red')
                color = [1,0,0];
            end
            app.StatusReporter.FontColor = color;
            app.StatusReporter.Text = 'Status: ' + string(msg);
        end
        
        function drawHyperparameterTuningPanel(app)
            
            % First get the height of the Inner Panel
            innerPanelPos = getPos(app, app.HyperparameterSettingPanel.Position, [35 10 560 171]);
            innerPanel = uipanel(app.HyperparameterSettingPanel, "Position", innerPanelPos, "Scrollable", "on","BorderType","none");
            
            % Innitialising the app.selectedHyperparameterSettings
            try
                app.selectedHyperparameterSettings = struct('hyperParamNames', {app.selectedClassifier.hyperParamNames},...
                                                        'inputType', {cell(1, length(app.selectedClassifier.hyperParamNames))},...
                                                        'ranges', {cell(1, length(app.selectedClassifier.hyperParamNames))},...
                                                        'type', 'Grid Search Method',...
                                                        'saveAllResults',false);
                for i = 1:length(app.selectedClassifier.hyperParamNames)
                    app.selectedHyperparameterSettings.inputType{i} = 'Discrete Values';
                end
            catch
                updateStatusReporter(app,"No Hyperparameter Settings found for Classifier", 'red')
                return
            end
            
            % Used to place different components on sequential lines
            if length(app.selectedClassifier.hyperParamNames) > 7
                lineNo = 7 - length(app.selectedClassifier.hyperParamNames);
            else
                lineNo = 0;
            end
            
            for hyperParamIDX = 1:length(app.selectedClassifier.hyperParamNames)
                % Adding the label for the parameter name
                HyperParamLabel = uilabel(innerPanel, "Text", app.selectedClassifier.hyperParamNames(hyperParamIDX));
                HyperParamLabel.Position = getPos(app, innerPanelPos, [lineNo*22 5 90 22]);
                
                if strcmp(HyperParamLabel.Text, 'kerfParaType')
                    % Drop down to select the type of input.
                    app.selectedHyperparameterSettings.inputType{hyperParamIDX}='kerfParaType';
                    kerfParaTypeEditField = uieditfield(innerPanel, "Placeholder", "Add option for selected Kernel Function (Default:'lin')", "ValueChangedFcn",{@kerfParaTypeEditFieldChanged, app, hyperParamIDX});
                    kerfParaTypeEditField.Position = getPos(app, innerPanelPos, [lineNo*22 100 440 22]);
                else
                    % The input based on the selected below dd. all are present but invisible at first.
                    discreteValueTextArea = uieditfield(innerPanel, "Placeholder", "Add values seperated by commas/spaces (Default:1)", "Visible","on", "ValueChangedFcn", {@discreteValueTextAreaChanged, app, hyperParamIDX});
                    discreteValueTextArea.Position = getPos(app, innerPanelPos, [lineNo*22 225 315 22]);
                    
                    % Min Max Steps (linear steps)
                    maxMinStepPanelPos = getPos(app, innerPanelPos, [lineNo*22 225 315 22]);
                    maxMinStepPanel = uipanel(innerPanel, "Position", maxMinStepPanelPos, "BorderType", "none", "Visible","off");
                    
                    minTextArea = uieditfield(maxMinStepPanel, 'numeric', "Tooltip", "Min", "Value",-1000, "ValueChangedFcn",{@maxMinStepPanelChanged, app, hyperParamIDX});
                    minTextArea.Position = getPos(app, maxMinStepPanelPos, [0 1 100 22]);
                    
                    stepTextArea = uieditfield(maxMinStepPanel, 'numeric', "Tooltip", "Step size", "Limits",[0 Inf],"LowerLimitInclusive","off", "Value",100, "ValueChangedFcn",{@maxMinStepPanelChanged, app, hyperParamIDX});
                    stepTextArea.Position = getPos(app, maxMinStepPanelPos, [0 106 100 22]);
                    
                    maxTextArea = uieditfield(maxMinStepPanel, 'numeric', "Tooltip", "Max", "Value",1000, "ValueChangedFcn",{@maxMinStepPanelChanged, app, hyperParamIDX});
                    maxTextArea.Position = getPos(app, maxMinStepPanelPos, [0 211 100 22]);
                    
                    % Exponential Steps
                    exponentialStepsPanelPos = getPos(app, innerPanelPos, [lineNo*22 225 315 22]);
                    exponentialStepsPanel = uipanel(innerPanel, "Position", exponentialStepsPanelPos, "BorderType", "none", "Visible","off");
                    
                    baseTextArea = uieditfield(exponentialStepsPanel, 'numeric', "Tooltip", "Base","Limits", [1 Inf],'LowerLimitInclusive','off', 'value', 2, "ValueChangedFcn",{@exponentialStepsPanelChanged, app, hyperParamIDX});
                    baseTextArea.Position = getPos(app, exponentialStepsPanelPos, [0 1 75 22]);
                    
                    minTextArea = uieditfield(exponentialStepsPanel, 'numeric', "Tooltip", "Min",'RoundFractionalValues','on', "Value",-5, "ValueChangedFcn",{@exponentialStepsPanelChanged, app, hyperParamIDX});
                    minTextArea.Position = getPos(app, exponentialStepsPanelPos, [0 80 75 22]);
                    
                    stepTextArea = uieditfield(exponentialStepsPanel, 'numeric', "Tooltip", "Step size", 'value', 1,'RoundFractionalValues','on', "ValueChangedFcn",{@exponentialStepsPanelChanged, app, hyperParamIDX});
                    stepTextArea.Position = getPos(app, exponentialStepsPanelPos, [0 160 75 22]);
                    
                    maxTextArea = uieditfield(exponentialStepsPanel, 'numeric', "Tooltip", "Max",'RoundFractionalValues','on',"Value",5, "ValueChangedFcn",{@exponentialStepsPanelChanged, app, hyperParamIDX});
                    maxTextArea.Position = getPos(app, exponentialStepsPanelPos, [0 240 75 22]);
                    
                    % Drop down to select the type of input.
                    inputTypeDD = uidropdown(innerPanel, 'Items', {'Discrete Values', 'Min-Step-Max (Linear Steps)', 'Base-Min-Step-Max (Exponential Steps)'}, "ValueChangedFcn",{@changeInputStyle, app, hyperParamIDX, discreteValueTextArea, maxMinStepPanel, exponentialStepsPanel});
                    inputTypeDD.Position = getPos(app, innerPanelPos, [lineNo*22 100 120 22]);
                end
                
                lineNo = lineNo + 1.1;
            end
            
            function kerfParaTypeEditFieldChanged(src, ~, app, index)
                app.selectedHyperparameterSettings.ranges{index}=string(src.Value);
            end
            
            function exponentialStepsPanelChanged(src, ~, app, index)
                % The next line is added for when someone changes the settings after they are calculated.
                app.selectedHyperparameterSettings.inputType{index} = 'Base-Min-Step-Max (Exponential Steps)';
                if strcmp(src.Tooltip, 'Base')
                    app.selectedHyperparameterSettings.ranges{index}{1} = src.Value;
                elseif strcmp(src.Tooltip, 'Min')
                    app.selectedHyperparameterSettings.ranges{index}{2} = src.Value;
                elseif strcmp(src.Tooltip, 'Step size')
                    app.selectedHyperparameterSettings.ranges{index}{3} = src.Value;
                elseif strcmp(src.Tooltip, 'Max')
                    app.selectedHyperparameterSettings.ranges{index}{4} = src.Value;
                end
            end
            
            function maxMinStepPanelChanged(src, ~, app, index)
                % The next line is added for when someone changes the settings after they are calculated.
                app.selectedHyperparameterSettings.inputType{index} = 'Min-Step-Max (Linear Steps)';
                if strcmp(src.Tooltip, 'Min')
                    app.selectedHyperparameterSettings.ranges{index}{1} = src.Value;
                elseif strcmp(src.Tooltip, 'Step size')
                    app.selectedHyperparameterSettings.ranges{index}{2} = src.Value;
                elseif strcmp(src.Tooltip, 'Max')
                    app.selectedHyperparameterSettings.ranges{index}{3} = src.Value;
                end
            end
            
            % Function for discreteValueTextArea to send data into app.selectedHyperparameterSettings
            function discreteValueTextAreaChanged(src, ~, app, index)
                % try to check if the input is correct.
                flag = true;
                while flag
                    range = str2num(src.Value);
                    if isempty(range)
                        msg = 'Please check the input values only numbers seperated by commas/spaces or matlab array operation e.g. "0.1.*[-5:5]" are supported.';
                        title = 'Input Error';
                        dims = 1;
                        default = {src.Value};
                        response = inputdlg(msg, title, dims, default);
                        if isempty(response)
                            response = {''};
                            flag = false;
                        end
                        src.Value = response{:};
                    else
                        flag = false;
                    end
                end
                app.selectedHyperparameterSettings.ranges{index} = range;
            end
            
            % Function for the DD that changes input style
            function changeInputStyle(~, event, app, index, discreteValueTextArea, maxMinStepPanel, exponentialStepsPanel)
                app.selectedHyperparameterSettings.inputType{index} = event.Value;
                if strcmp(event.Value, 'Min-Step-Max (Linear Steps)')
                    discreteValueTextArea.Visible = 'off';
                    maxMinStepPanel.Visible = 'on';
                    exponentialStepsPanel.Visible = 'off';
                    
                    % Default Values.
                    app.selectedHyperparameterSettings.ranges{index}{1} = -1000;
                    app.selectedHyperparameterSettings.ranges{index}{2} = 100;
                    app.selectedHyperparameterSettings.ranges{index}{3} = 1000;
                elseif strcmp(event.Value, 'Discrete Values')
                    discreteValueTextArea.Visible = 'on';
                    maxMinStepPanel.Visible = 'off';
                    exponentialStepsPanel.Visible = 'off';
                elseif strcmp(event.Value, 'Base-Min-Step-Max (Exponential Steps)')
                    discreteValueTextArea.Visible = 'off';
                    maxMinStepPanel.Visible = 'off';
                    exponentialStepsPanel.Visible = 'on';
                    
                    % Default Values.
                    app.selectedHyperparameterSettings.ranges{index}{1} = 2;
                    app.selectedHyperparameterSettings.ranges{index}{2} = -5;
                    app.selectedHyperparameterSettings.ranges{index}{3} = 1;
                    app.selectedHyperparameterSettings.ranges{index}{4} = 5;
                end
            end
        end
        
        function results = getPos(~, parentPos, topLeftPosSize)
            results = [topLeftPosSize(2) parentPos(4)-topLeftPosSize(4)-topLeftPosSize(1) topLeftPosSize(3) topLeftPosSize(4)];
        end
        
        function [optPara, maxResult] = Optimise_Classifier(app, filename)
            % load the location of the Classifier to Matlab Paths
            addpath(genpath(string(app.selectedClassifier.path)));
            
            % Get Hyperparameters to tune on
            % TODO: For the infinite versions we will need a while true loop
            [hyperParams] = getTuningHyperparameters(app);
            
            % If we need to save all Results Innitialising variable
            if app.selectedHyperparameterSettings.saveAllResults
                allResultsFileOut = '';
                for j = 1:length(app.selectedHyperparameterSettings.hyperParamNames)
                    allResultsFileOut = [allResultsFileOut char(app.selectedHyperparameterSettings.hyperParamNames(j)) '\t'];
                end
                
                allResultsFileOut = [allResultsFileOut sprintf('%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\n', ["AUC", "Accuracy", "Sensitivity", "Specificity", "Precision", "F-Measure", "G-Mmean", "Train-Time", "Standard Deviation(Accuracy)"])];
            end
            
            maxResult = [];
            
            for i = 1:height(hyperParams)
                % this allows the loop to be interrupted
                pause(0.01);
                drawnow();
                if app.stopTraining
                    updateStatusReporter(app, 'Training forcefully stopped by user.', 'red');
                    break;
                end
                
                % display the percentage completed.
                percentage = string(round(((i-1)*100)/height(hyperParams),2));
                percentage = char(percentage);
                trainingStartMsg = ['Undergoing Training | Current Dataset: ', filename, ' | completion: ', percentage, '%'];
                updateStatusReporter(app, trainingStartMsg, 'green');
                
                hyperParam = hyperParams(i,:);
                
                % Construct the funPara
                funPara = struct();
                for j = 1:length(app.selectedHyperparameterSettings.hyperParamNames)
                    funPara.(string(app.selectedHyperparameterSettings.hyperParamNames(j))) = hyperParam.(j);
                end
                
                [results, standardDeviation] = performCrossValidationTraining(app, filename, funPara);
                
                if isempty(maxResult)
                    maxResult = results;
                    optPara = funPara;
                    optPara.acc = maxResult(2);
                    optPara.std = standardDeviation;
                else
                    if results(2)>maxResult(2)
                        maxResult = results;
                        optPara = funPara;
                        optPara.acc = maxResult(2);
                        optPara.std = standardDeviation;
                    end
                end
                
                if app.selectedHyperparameterSettings.saveAllResults
                    for j = 1:length(app.selectedHyperparameterSettings.hyperParamNames)
                        allResultsFileOut = [allResultsFileOut char(string(hyperParam.(j))) '\t'];
                    end
                    allResultsFileOut = [allResultsFileOut sprintf('%g\t%g\t%g\t%g\t%g\t%g\t%g\t%g\t%g\n', maxResult, optPara.std)];
                end
            end
            
            if app.selectedHyperparameterSettings.saveAllResults
                fprintf(fopen(replace([app.saveLocation filename(1:end-4) '_allHyperparameterResults.txt'],'\','/'), 'a+'), allResultsFileOut);
            end
            % remove the location of the Classifier from Matlab Paths
            rmpath(genpath(string(app.selectedClassifier.path)));
        end
        
        function [hyperParams] = getTuningHyperparameters(app)
            hyperparamInfo = app.selectedHyperparameterSettings;
            % Grid Search Method
            if strcmp(hyperparamInfo.type, 'Grid Search Method')
                hyperParams = cartesian_product(hyperparamInfo.ranges{:});
                
            % Random Search Method
            elseif strcmp(hyperparamInfo.type, 'Random Search Method')
                hyperParams = cartesian_product(hyperparamInfo.ranges{:});
                lenHyperParams = height(hyperParams);
                if strcmp(hyperparamInfo.randSizeType, 'Percentage of Grid')
                    randSize = floor(hyperparamInfo.randSize * lenHyperParams/100);
                    idx = randperm(lenHyperParams, randSize);
                elseif strcmp(hyperparamInfo.randSizeType, 'Number of iterations')
                    idx = randperm(lenHyperParams, hyperparamInfo.randSize);
                end
                hyperParams = hyperParams(idx,:);
            end
            function tbl = cartesian_product(varargin)
                names = arrayfun(@inputname, 1:nargin, 'UniformOutput', false);
                for i = 1:nargin
                    if isempty(names{i})
                        names{i} = ['var' num2str(i)];
                    end
                end
                rev_args = flip(varargin);      
                [A{1:nargin}] = ndgrid(rev_args{:});
                B = cellfun(@(x) x(:), A, 'UniformOutput', false);
                C = flip(B);
                tbl = table(C{:}, 'VariableNames', names);
            end
        end
        
        
        function [results, standardDeviation] = performCrossValidationTraining(app, filename, funPara)
            % Loading Dataset
            datasetLocation = strcat(app.DatasetSelectionAppResponse.datasetsFolder, filename);
            data = load(datasetLocation);
            
            % Applying Crossvalidation.
            if strcmp(app.DatasetSelectionAppResponse.testingMethod, 'KFoldCrossValidation')
                % Innitialising allMeasurements.
                allMeasurements = zeros(app.DatasetSelectionAppResponse.crossValidationParameter, 8);
                
                % TODO: Below commented out as for svm based models concept of validation is less used.
%                 randIdxs = randperm(length(data));
%                 validationData = data(randIdxs(1:floor(app.DatasetSelectionAppResponse.crossValidationValidationSplit*length(data)/100)), :);
%                 data = data(randIdxs(floor(app.DatasetSelectionAppResponse.crossValidationValidationSplit*length(data)/100)+1:end),:);
                
                indices = crossvalind('Kfold', length(data), app.DatasetSelectionAppResponse.crossValidationParameter);
                % k-fold Cross validation
                for i = 1:app.DatasetSelectionAppResponse.crossValidationParameter
                    test = (indices == i); 
                    train = ~test;
                    % feval is used to call a function by its name stored in a variable
                    try
                        [classifierPredictions, train_tym] = feval(app.selectedClassifier.fnName, data(test,:), data(train,:), funPara);
                    catch ME
                        updateStatusReporter(app, ['Error using classifier: ' ME.identifier], 'red');
                        break;
                        % TODO: Add code to reset app.
                    end
                    
                    % Evaluation % TODO: Automate the pos_class entry.
                    [AUC, accuracy, sensitivity, specificity, precision, f_measure, gmean] = Evaluate(app, data(test,end), classifierPredictions, max(data(test,end)));
                    allMeasurements(i,:) = [AUC accuracy sensitivity specificity precision f_measure gmean train_tym];
                end
                results = mean(allMeasurements);
                standardDeviation = std(allMeasurements(:,2));
            elseif strcmp(app.DatasetSelectionAppResponse.testingMethod, 'HoldOutCrossValidation')
                indices = crossvalind('HoldOut', length(data), app.DatasetSelectionAppResponse.crossValidationParameter);
                
                % feval is used to call a function by its name stored in a variable
                [classifierPredictions, train_tym] = feval(app.selectedClassifier.fnName, data(~indices,:), data(indices,:), funPara);
                
                % Evaluation % TODO: Automate the pos_class entry.
                [AUC, accuracy, sensitivity, specificity, precision, f_measure, gmean] = Evaluate(app, data(~indices,end), classifierPredictions, max(data(~indices,end)));
                
                results = [AUC accuracy sensitivity specificity precision f_measure gmean train_tym];
                standardDeviation = 0;
            elseif strcmp(app.DatasetSelectionAppResponse.testingMethod, 'LeaveMOutCrossValidation')
                % Innitialising allMeasurements.
                allMeasurements = zeros(floor(length(data)/app.DatasetSelectionAppResponse.crossValidationParameter), 8);
                
                for i = 1:floor(length(data)/app.DatasetSelectionAppResponse.crossValidationParameter)
                    indices = crossvalind('LeaveMOut', length(data), app.DatasetSelectionAppResponse.crossValidationParameter);
                    % feval is used to call a function by its name stored in a variable
                    [classifierPredictions, train_tym] = feval(app.selectedClassifier.fnName, data(~indices,:), data(indices,:), funPara);
                    
                    % Evaluation % TODO: Automate the pos_class entry.
                    [AUC, accuracy, sensitivity, specificity, precision, f_measure, gmean] = Evaluate(app, data(~indices,end), classifierPredictions, max(data(~indices,end)));
                    allMeasurements(i,:) = [AUC accuracy sensitivity specificity precision f_measure gmean train_tym];
                end
                results = mean(allMeasurements);
                standardDeviation = std(allMeasurements(:,2));
            elseif strcmp(app.DatasetSelectionAppResponse.testingMethod, 'TrainTestValidation')
                % Making a array of random indexes.
                randIdxs = randperm(length(data));
                % Using above to create train test splits.
                train = data(randIdxs(1:floor(app.DatasetSelectionAppResponse.trainSplit*length(data)/100)),:);
                test = data(randIdxs(floor(app.DatasetSelectionAppResponse.trainSplit*length(data)/100)+1:end),:);
                
                [classifierPredictions, train_tym] = feval(app.selectedClassifier.fnName, test, train, funPara);
                [AUC, accuracy, sensitivity, specificity, precision, f_measure, gmean] = Evaluate(app, test(:,end), classifierPredictions, max(test(:,end)));
                
                results = [AUC accuracy sensitivity specificity precision f_measure gmean train_tym];
                standardDeviation = 0;
            end
        end
        
        function [AUC, accuracy, sensitivity, specificity, precision, f_measure, gmean] = Evaluate(~,ACTUAL,PREDICTED,pos_class)
            idx = (ACTUAL()==pos_class);

            p = length(ACTUAL(idx));
            n = length(ACTUAL(~idx));
            N = p+n;
            
            tp = sum(ACTUAL(idx)==PREDICTED(idx));
            tn = sum(ACTUAL(~idx)==PREDICTED(~idx));
            fp = n-tn;
            % fn = p-tp;
            
            if p~=0
                tp_rate = tp/p;
            else
                tp_rate=0;
            end
            if n~=0
                tn_rate = tn/n;
            else
                tn_rate=0;
            end
            fp_rate=1-tn_rate;
            
            AUC=(1+tp_rate-fp_rate)/2;
            accuracy = (tp+tn)/N;
            sensitivity = tp_rate;
            specificity = tn_rate;
            precision = tp/(tp+fp);
            recall = sensitivity;
            f_measure = 2*((precision*recall)/(precision + recall));
            gmean = sqrt(precision*recall);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % try loading app settings
            try
                app.appSettings = load(fullfile(app.absPath, 'appSettings.mat'));
            catch
                warning('No Default Settings Found.');
            end
            loadClassifiersToTree(app);
        end

        % Button pushed function: LoadDatasetButton
        function LoadDatasetButtonPushed(app, event)
            % Disable Load Dataset button while dialog is open
            app.LoadDatasetButton.Enable = 'off';
            
            % Call dialog box with input values 
            app.DatasetSelectionApp = DatasetSelectionScreen(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DatasetSelectionApp);
            delete(app);
        end

        % Selection changed function: ClassifierSelectionTree
        function ClassifierSelectionTreeSelectionChanged(app, event)
            selectedNodes = app.ClassifierSelectionTree.SelectedNodes;
            % Decode the selectedNode:
            if strcmp(selectedNodes.NodeData.nodeType, 'classifier')
                % decodedNodeData = app.ClassifierDecoder(selectedNodes.NodeData);
                % Above line commented as ClassifierDecoder cannot be
                % predetermined.
                updateStatusReporter(app, 'Load or Configure Classifier', 'black');
                if selectedNodes.NodeData.configureClassifierButtonEnable == "true"
                    app.ConfigureClassifierButton.Text = 'Configure Classifier';
                    app.ConfigureClassifierButton.Enable = 'on';
                else
                    app.ConfigureClassifierButton.Text = 'Load Classifier';
                    app.ConfigureClassifierButton.Enable = 'on';
                end
            elseif strcmp(selectedNodes.NodeData.nodeType, 'noSettingsClassifier')
                updateStatusReporter(app, 'No Settings for the Selected Classifier', 'red');
                app.ConfigureClassifierButton.Enable = 'off';
            elseif strcmp(selectedNodes.NodeData.nodeType, 'classifierType')
                updateStatusReporter(app, 'Expand node to select a Classifier', 'black');
                app.ConfigureClassifierButton.Enable = 'off';
            end
            
            app.CustomClassifierLoadingStatus.Visible = 'off';
        end

        % Button pushed function: ConfigureClassifierButton
        function ConfigureClassifierButtonPushed(app, event)
            % Once this button is pushed we should disable the Tree and
            % Custom Classifier Button
            app.ClassifierSelectionTree.Enable = 'off';

            % The Classifier loading function of the button
            if strcmp(app.ConfigureClassifierButton.Text,'Load Classifier')
                decodedNodeData = app.ClassifierSelectionTree.SelectedNodes.NodeData;
                app.selectedClassifier = decodedNodeData;
                app.selectedClassifier.path = app.appSettings.classifiersPath + app.selectedClassifier.baseClassifierType + '\' + app.selectedClassifier.classifierName + '\';
                
                app.CustomClassifierLoadingStatus.Text = ['Loaded file: ', char(decodedNodeData.fnName)];
                app.CustomClassifierLoadingStatus.FontColor = [0.39,0.83,0.07];
                app.CustomClassifierLoadingStatus.Visible = 'on';
                
                % TODO: Draw the HyperparameterTuningPanel
                drawHyperparameterTuningPanel(app);
                
                app.HyperparameterTuningPanel.Enable = 'on';
                
                % After this button is pressed it should be converted to "Cancel"
                app.ConfigureClassifierButton.Text = 'Cancel';
                app.ConfirmHyperparameterSettingsButton.Text = 'Confirm Hyperparameter Settings';
                app.HyperparameterSettingPanel.Enable = 'on';
                app.StartButton.Enable = 'off';
            elseif strcmp(app.ConfigureClassifierButton.Text,'Configure Classifier')
                % TODO: Add code here to open a new dialog box.
                app.CustomClassifierLoadingStatus.Text = 'Configuring';
                app.CustomClassifierLoadingStatus.FontColor = [0.39,0.83,0.07];
                app.CustomClassifierLoadingStatus.Visible = 'on';
                
            elseif strcmp(app.ConfigureClassifierButton.Text,'Cancel')
                % TODO: Add Code here to unload the Loaded Classifier.
                app.selectedClassifier = [];
                
                app.HyperparameterTuningPanel.Enable = 'off';
                
                app.CustomClassifierLoadingStatus.Visible = 'off';
                app.ClassifierSelectionTree.Enable = 'on';
                % After this button is pressed it should be converted to "Load Classifier", as obviously it was "Load Classifier" before.
                app.ConfigureClassifierButton.Text = 'Load Classifier';
            end
            
            
        end

        % Menu selected function: SaveCurrentConfigurationMenu
        function SaveCurrentConfigurationMenuSelected(app, event)
            settings = struct('defaultPath', app.absPath, 'classifiersPath', app.appSettings.classifiersPath, 'resultsSaveLocation', app.appSettings.resultsSaveLocation);
            try
                save(string(app.absPath) + '\appSettings.mat', '-struct', 'settings');
                updateStatusReporter(app, 'Saving Successful!', 'green');
            catch ME
                updateStatusReporter(app, ['Saving Failed | Error: ', ME.identifier], 'red');
            end
        end

        % Menu selected function: ChangeClassifiersDirectoryMenu
        function ChangeClassifiersDirectoryMenuSelected(app, event)
            app.appSettings.classifiersPath = [char(uigetdir(app.appSettings.defaultPath, 'Select Classifiers Folder')) '\'];
            % For focus
            drawnow; 
            figure(app.UIFigure);
            startupFcn(app);
        end

        % Menu selected function: LoadConfigurationMenu
        function LoadConfigurationMenuSelected(app, event)
            [file,path] = uigetfile('*.mat','Select Settings File');
            % For focus
            drawnow; 
            figure(app.UIFigure);
            try
                app.appSettings = load(fullfile(path,file));
                updateStatusReporter(app, 'Loading Successful!', 'green');
            catch ME
                updateStatusReporter(app, ['Loading Failed | Error: ', ME.identifier], 'red');
            end
            startupFcn(app);
        end

        % Menu selected function: RefreshClassifiersMenu
        function RefreshClassifiersMenuSelected(app, event)
            loadClassifiersToTree(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = 'off';
            app.stopTraining = false;
            app.StopButton.Enable = 'on';
            app.saveLocation = [char(app.appSettings.resultsSaveLocation) '\' replace(char(datetime('now')),':',"'") ' ' char(app.selectedClassifier.fnName) '\'];
            mkdir(app.saveLocation);
            app.resultsFile = fopen([app.saveLocation 'results.txt'], 'a+');
            
            % Log
            resultsLine = ['Datasets used: ' replace(char(app.DatasetSelectionAppResponse.datasetsFolder),'\','/') '\n'];
            resultsLine = [resultsLine 'Dataset Splitting Method Used: ' char(app.DatasetSelectionAppResponse.testingMethod) '\n'];
            if strcmp(app.DatasetSelectionAppResponse.testingMethod, 'TrainTestValidation')
                resultsLine = [resultsLine 'Training Split Used: ' char(string(app.DatasetSelectionAppResponse.trainSplit)) '\n'];
                resultsLine = [resultsLine 'Testing Split Used: ' char(string(app.DatasetSelectionAppResponse.testSplit)) '\n'];
            else
                resultsLine = [resultsLine 'Crossvalidation Parameter Used: ' char(string(app.DatasetSelectionAppResponse.crossValidationParameter)) '\n'];
            end
            resultsLine = [resultsLine 'Classifier Used: ' char(app.selectedClassifier.classifierName) '\n'];
            resultsLine = [resultsLine 'Hyperparameter Settings Used: \ntype: ' char(app.selectedHyperparameterSettings.type) ', Parameter Ranges:\n'];
            
            for hyperParamIDX = 1:length(app.selectedClassifier.hyperParamNames)
                range = app.selectedHyperparameterSettings.ranges(hyperParamIDX);
                resultsLine = [resultsLine char(app.selectedClassifier.hyperParamNames(hyperParamIDX)) ': ' strjoin(string(range{:})) '\n'];
            end
            
            resultsLine = [resultsLine 'Results:\n'];
            fprintf(app.resultsFile, strjoin(resultsLine));

            resultsLine = sprintf('\n%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\t%s\n', "Dataset", ["AUC", "Accuracy", "Sensitivity", "Specificity", "Precision", "F-Measure", "G-Mmean", "Train-Time", "Standard Deviation(Accuracy)"]);
            fprintf(app.resultsFile, resultsLine);
            app.parametersFile = fopen([app.saveLocation 'parameters.txt'], 'a+');
            
            files = dir(strcat(app.DatasetSelectionAppResponse.datasetsFolder, app.DatasetSelectionAppResponse.datasetExtension));
            
            for i = 1:length(files)
                % this allows the loop to be interrupted
                pause(0.01);
                drawnow();
                if app.stopTraining
                    updateStatusReporter(app, 'Training forcefully stopped by user.', 'red');
                    break;
                end
                
                % process and retrive the dataset
                [optPara, maxResult] = Optimise_Classifier(app, files(i).name);
                resultsLine = sprintf('%s\t%g\t%g\t%g\t%g\t%g\t%g\t%g\t%g\t%g\n', files(i).name, maxResult, optPara.std);
                fieldNames = string(fieldnames(optPara));
                parametersLine = char(files(i).name);
                for j = 1:length(fieldNames)
                    try
                        parametersLine = [char(parametersLine) '\t' char(fieldNames(j)) '\t' char(string(optPara.(fieldNames(j))))];
                    catch ME
                        updateStatusReporter(app, ['Error: ' ME.identifier], 'red');
                    end
                end
                parametersLine = [parametersLine '\n'];
                
                % Save results.
                fprintf(app.resultsFile, resultsLine);
                fprintf(app.parametersFile, parametersLine);
            end
            fclose('all');
            if ~app.stopTraining
                updateStatusReporter(app, 'Training and Testing Finished', 'green');
            end
            app.StartButton.Enable = 'on';
            app.StopButton.Enable = 'off';
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopTraining = true;
            app.StopButton.Enable = 'off';
            updateStatusReporter(app, 'Stopping...', 'red');
        end

        % Value changed function: TuningmethodDropDown
        function TuningmethodDropDownValueChanged(app, event)
            value = app.TuningmethodDropDown.Value;
            app.selectedHyperparameterSettings.type = value;
            if strcmp(value, 'Random Search Method')
                
                app.selectedHyperparameterSettings.randSizeType = 'Percentage of Grid';
                app.selectedHyperparameterSettings.randSize = 50;
                
                app.RandomSizeDropDownEditField.Visible = 'on';
                app.RandomSizeTypeDropDown.Visible = 'on';
            else
                app.RandomSizeDropDownEditField.Visible = 'off';
                app.RandomSizeTypeDropDown.Visible = 'off';
            end
        end

        % Button pushed function: 
        % ConfirmHyperparameterSettingsButton
        function ConfirmHyperparameterSettingsButtonPushed(app, event)
            % TODO: add a check for if any of the fields is empty.
            
            app.HyperparameterSettingPanel.Enable = 'off';
            if strcmp(app.ConfirmHyperparameterSettingsButton.Text, 'Confirm Hyperparameter Settings')
                for i = 1:length(app.selectedClassifier.hyperParamNames)
                    % Check if field is empty (in case of Discrete values)
                    if isempty(app.selectedHyperparameterSettings.ranges{i})
                        if strcmp(app.selectedHyperparameterSettings.inputType{i}, 'kerfParaType')
                            app.selectedHyperparameterSettings.ranges{i} = "lin";
                        else
                            app.selectedHyperparameterSettings.ranges{i} = 1;
                        end
                    end
                    
                    if strcmp(app.selectedHyperparameterSettings.inputType{i}, 'Min-Step-Max (Linear Steps)')
                        % This is added so that they are not re-calculated.
                        app.selectedHyperparameterSettings.inputType{i} = 'Min-Step-Max Solved';
                        
                        app.selectedHyperparameterSettings.ranges{i} = app.selectedHyperparameterSettings.ranges{i}{1}:app.selectedHyperparameterSettings.ranges{i}{2}:app.selectedHyperparameterSettings.ranges{i}{3};
                    elseif strcmp(app.selectedHyperparameterSettings.inputType{i}, 'Base-Min-Step-Max (Exponential Steps)')
                        % This is added so that they are not re-calculated.
                        app.selectedHyperparameterSettings.inputType{i} = 'Base-Min-Step-Max Solved';
                        
                        app.selectedHyperparameterSettings.ranges{i} = app.selectedHyperparameterSettings.ranges{i}{1}.^(app.selectedHyperparameterSettings.ranges{i}{2}:app.selectedHyperparameterSettings.ranges{i}{3}:app.selectedHyperparameterSettings.ranges{i}{4});
                    end
                end
                app.StartButton.Enable = 'on';
                app.ConfirmHyperparameterSettingsButton.Text = 'Cancel';
            elseif strcmp(app.ConfirmHyperparameterSettingsButton.Text, 'Cancel')
                app.HyperparameterSettingPanel.Enable = 'on';
                app.ConfirmHyperparameterSettingsButton.Text = 'Confirm Hyperparameter Settings';
                app.StartButton.Enable = 'off';
            end
        end

        % Value changed function: 
        % SaveResultsforallHyperparametersCheckBox
        function SaveResultsforallHyperparametersCheckBoxValueChanged(app, event)
            value = app.SaveResultsforallHyperparametersCheckBox.Value;
            app.selectedHyperparameterSettings.saveAllResults = value;
        end

        % Value changed function: RandomSizeTypeDropDown
        function RandomSizeTypeDropDownValueChanged(app, event)
            value = app.RandomSizeTypeDropDown.Value;
            app.selectedHyperparameterSettings.randSizeType = value;
            if strcmp(value, 'Percentage of Grid')
                app.RandomSizeDropDownEditField.Limits = [0,100];
            else
                app.RandomSizeDropDownEditField.Value = 10;
                app.selectedHyperparameterSettings.randSize = 10;
                app.RandomSizeDropDownEditField.Limits = [0,Inf];
            end
        end

        % Value changed function: RandomSizeDropDownEditField
        function RandomSizeDropDownEditFieldValueChanged(app, event)
            value = app.RandomSizeDropDownEditField.Value;
            app.selectedHyperparameterSettings.randSize = value;
        end

        % Menu selected function: ChangeSaveLocationMenu
        function ChangeSaveLocationMenuSelected(app, event)
            app.appSettings.resultsSaveLocation = [char(uigetdir(app.appSettings.defaultPath,'Select Save Folder')) '\'];
            % For focus
            drawnow; 
            figure(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveCurrentConfigurationMenu = uimenu(app.FileMenu);
            app.SaveCurrentConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCurrentConfigurationMenuSelected, true);
            app.SaveCurrentConfigurationMenu.Text = 'Save Current Configuration';
            app.LoadConfigurationMenu = uimenu(app.FileMenu);
            app.LoadConfigurationMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConfigurationMenuSelected, true);
            app.LoadConfigurationMenu.Text = 'Load Configuration';
            app.ChangeClassifiersDirectoryMenu = uimenu(app.FileMenu);
            app.ChangeClassifiersDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeClassifiersDirectoryMenuSelected, true);
            app.ChangeClassifiersDirectoryMenu.Text = 'Change Classifiers Directory';
            app.ChangeSaveLocationMenu = uimenu(app.FileMenu);
            app.ChangeSaveLocationMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeSaveLocationMenuSelected, true);
            app.ChangeSaveLocationMenu.Text = 'Change Save Location';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.RefreshClassifiersMenu = uimenu(app.OptionsMenu);
            app.RefreshClassifiersMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshClassifiersMenuSelected, true);
            app.RefreshClassifiersMenu.Text = 'Refresh Classifiers';
            app.LoadDatasetButton = uibutton(app.UIFigure, 'push');
            app.LoadDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDatasetButtonPushed, true);
            app.LoadDatasetButton.Text = 'Load Dataset';
            app.ChooseClassifierPanel = uipanel(app.UIFigure);
            app.ChooseClassifierPanel.Enable = 'off';
            app.ChooseClassifierPanel.Title = 'Choose Classifier';
            app.ClassifierSelectionTree = uitree(app.ChooseClassifierPanel);
            app.ClassifierSelectionTree.SelectionChangedFcn = createCallbackFcn(app, @ClassifierSelectionTreeSelectionChanged, true);
            app.Hyperlink = uihyperlink(app.ChooseClassifierPanel);
            app.Hyperlink.URL = 'https://github.com/JatinJangir24/BTP_MATLAB_APP/blob/master/InstructionsForCustomClassifiers.md';
            app.Hyperlink.Text = 'Instructions for Custom Classifier';
            app.CustomClassifierLoadingStatus = uilabel(app.ChooseClassifierPanel);
            app.CustomClassifierLoadingStatus.Visible = 'off';
            app.CustomClassifierLoadingStatus.Text = 'Loaded File: ';
            app.ConfigureClassifierButton = uibutton(app.ChooseClassifierPanel, 'push');
            app.ConfigureClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureClassifierButtonPushed, true);
            app.ConfigureClassifierButton.Enable = 'off';
            app.ConfigureClassifierButton.Text = 'Configure Classifier';
            app.HyperparameterTuningPanel = uipanel(app.UIFigure);
            app.HyperparameterTuningPanel.Enable = 'off';
            app.HyperparameterTuningPanel.Title = 'Hyperparameter Tuning';
            app.HyperparameterSettingPanel = uipanel(app.HyperparameterTuningPanel);
            app.TuningmethodDropDownLabel = uilabel(app.HyperparameterSettingPanel);
            app.TuningmethodDropDownLabel.Text = 'Tuning method';
            app.TuningmethodDropDown = uidropdown(app.HyperparameterSettingPanel);
            app.TuningmethodDropDown.Items = {'Grid Search Method', 'Random Search Method'};
            app.TuningmethodDropDown.ValueChangedFcn = createCallbackFcn(app, @TuningmethodDropDownValueChanged, true);
            app.TuningmethodDropDown.Placeholder = 'Please Select';
            app.TuningmethodDropDown.Value = 'Grid Search Method';
            app.RandomSizeTypeDropDown = uidropdown(app.HyperparameterSettingPanel);
            app.RandomSizeTypeDropDown.Items = {'Percentage of Grid', 'Number of iterations'};
            app.RandomSizeTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RandomSizeTypeDropDownValueChanged, true);
            app.RandomSizeTypeDropDown.Visible = 'off';
            app.RandomSizeTypeDropDown.Value = 'Percentage of Grid';
            app.RandomSizeDropDownEditField = uieditfield(app.HyperparameterSettingPanel, 'numeric');
            app.RandomSizeDropDownEditField.LowerLimitInclusive = 'off';
            app.RandomSizeDropDownEditField.Limits = [0 100];
            app.RandomSizeDropDownEditField.ValueChangedFcn = createCallbackFcn(app, @RandomSizeDropDownEditFieldValueChanged, true);
            app.RandomSizeDropDownEditField.Visible = 'off';
            app.RandomSizeDropDownEditField.Value = 50;
            app.ConfirmHyperparameterSettingsButton = uibutton(app.HyperparameterTuningPanel, 'push');
            app.ConfirmHyperparameterSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmHyperparameterSettingsButtonPushed, true);
            app.ConfirmHyperparameterSettingsButton.Text = 'Confirm Hyperparameter Settings';
            app.SaveResultsforallHyperparametersCheckBox = uicheckbox(app.HyperparameterTuningPanel);
            app.SaveResultsforallHyperparametersCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveResultsforallHyperparametersCheckBoxValueChanged, true);
            app.SaveResultsforallHyperparametersCheckBox.Text = 'Save Results for all Hyperparameters';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.DatasetLoadingStatus = uilabel(app.UIFigure);
            app.DatasetLoadingStatus.Text = 'No Dataset(s) Loaded';
            app.StatusReporter = uilabel(app.UIFigure);
            app.StatusReporter.Text = 'Status:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end